classdef app1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RightPanel               matlab.ui.container.Panel
        LoadSpecificImageButton  matlab.ui.control.Button
        RedAxes_3                matlab.ui.control.UIAxes
        ImageAxes_3              matlab.ui.control.UIAxes
        CenterPanel              matlab.ui.container.Panel
        RedAxes_2                matlab.ui.control.UIAxes
        ImageAxes_4              matlab.ui.control.UIAxes
        LeftPanel                matlab.ui.container.Panel
        DropDown_2               matlab.ui.control.DropDown
        DropDown_2Label          matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        EditField_2Label         matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        GOButton                 matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        DropDownLabel            matlab.ui.control.Label
        LoadInputImageButton     matlab.ui.control.Button
        RedAxes                  matlab.ui.control.UIAxes
        ImageAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            app.CenterPanel.Visible = 'off';
            app.RightPanel.Visible ='off';
            app.EditField.Visible ='off';
            app.EditField_2.Visible ='off';
            app.EditFieldLabel.Visible = 'off';
            app.EditField_2Label.Visible = 'off';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if strcmp(value, "x")
                app.RightPanel.Visible = 'off';
                app.EditField_2.Visible ='off';
                app.EditFieldLabel.Visible = 'on';
                app.EditField_2Label.Visible = 'off';
                app.CenterPanel.Visible = 'on';
                app.EditField.Visible ='on';
            elseif strcmp(value, 'y')
                app.CenterPanel.Visible = 'off';
                app.EditField.Visible ='off';
                app.EditFieldLabel.Visible = 'off';
                app.EditField_2Label.Visible = 'on';
                app.RightPanel.Visible = 'on';
                app.EditField_2.Visible ='on';
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.ImageAxes = uiaxes(app.LeftPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.RedAxes = uiaxes(app.LeftPanel);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Grey Level')
            ylabel(app.RedAxes, 'Frequency')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.LoadInputImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadInputImageButton.Text = 'Load Input Image';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'x', 'y'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'x';
            app.GOButton = uibutton(app.LeftPanel, 'push');
            app.GOButton.Text = 'GO!';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.DropDown_2Label = uilabel(app.LeftPanel);
            app.DropDown_2Label.Text = 'Drop Down';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.Items = {'x', 'y'};
            app.DropDown_2.Value = 'x';
            app.CenterPanel = uipanel(app.UIFigure);
            app.ImageAxes_4 = uiaxes(app.CenterPanel);
            app.ImageAxes_4.XTick = [];
            app.ImageAxes_4.XTickLabel = {'[ ]'};
            app.ImageAxes_4.YTick = [];
            app.RedAxes_2 = uiaxes(app.CenterPanel);
            title(app.RedAxes_2, 'Red')
            xlabel(app.RedAxes_2, 'Grey Level')
            ylabel(app.RedAxes_2, 'Frequency')
            app.RedAxes_2.XLim = [0 255];
            app.RedAxes_2.XTick = [0 128 255];
            app.RightPanel = uipanel(app.UIFigure);
            app.ImageAxes_3 = uiaxes(app.RightPanel);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.XTickLabel = {'[ ]'};
            app.ImageAxes_3.YTick = [];
            app.RedAxes_3 = uiaxes(app.RightPanel);
            title(app.RedAxes_3, 'Red')
            xlabel(app.RedAxes_3, 'Grey Level')
            ylabel(app.RedAxes_3, 'Frequency')
            app.RedAxes_3.XLim = [0 255];
            app.RedAxes_3.XTick = [0 128 255];
            app.LoadSpecificImageButton = uibutton(app.RightPanel, 'push');
            app.LoadSpecificImageButton.Text = 'Load  Specific Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end